classdef GUI_testing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        txtTestWearStatus        matlab.ui.control.TextArea
        StatusTextAreaLabel      matlab.ui.control.Label
        btnTestCue               matlab.ui.control.Button
        NegativeSignLabel        matlab.ui.control.Label
        cheBoxNeg                matlab.ui.control.CheckBox
        statebtnRun              matlab.ui.control.StateButton
        ddwAlgo                  matlab.ui.control.DropDown
        AlogrithmDropDownLabel   matlab.ui.control.Label
        txtTestStatus            matlab.ui.control.TextArea
        ProgStatusTextAreaLabel  matlab.ui.control.Label
        txtIndicator             matlab.ui.control.TextArea
        IndicatorTextAreaLabel   matlab.ui.control.Label
        btnReset                 matlab.ui.control.Button
        btnStop                  matlab.ui.control.Button
        edtTime                  matlab.ui.control.NumericEditField
        TimeEditFieldLabel       matlab.ui.control.Label
        edtFre                   matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public) 
        cellArrayTextStatus % Text to show running status of high-level program
    end
    
    methods (Access = public)
        
         function outPutStatus(app,str)
            string = sprintf('%s\n', str);  % Transfer input str to 'string'
            app.cellArrayTextStatus = horzcat(app.cellArrayTextStatus,string); % Serial connect the string array for display
            app.txtTestStatus.Value = app.cellArrayTextStatus;  % Set the display
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: statebtnRun
        function statebtnRunValueChanged(app, event)
            global testP;
            global testTempApp;
            testTempApp = app;
            value = app.statebtnRun.Value;
            negValue = app.cheBoxNeg.Value;
            alogMode = app.ddwAlgo.Value;
            app.txtIndicator.Value = ' ';
            app.txtTestWearStatus.Value = ' ';
            app.cellArrayTextStatus = [];
            outPutStatus(app,'****Program Status****');
            if value == true
                testP = testSet_Parameters();
                try                    
                    testP.MainFreq = get(app.edtFre,'value');
                    testP.MaxRunTime = get(app.edtTime,'value');
                    testP.negEnable = negValue;
                    testP.alogMode = alogMode;
                catch
                    errordlg('Error in input');
                    return;
                end
                outPutStatus(app,'Program Start.');
                pause(5/1000);
                testMain();
            end
        end

        % Button pushed function: btnStop
        function btnStopButtonPushed(app, event)
            % Should click after see High-level Control Running (Into timer loop)
            global testP;
            set(app.statebtnRun,'value',0);
%             pause(5/1000);
            if(~isempty(testP.config{2,1}) && isvalid(testP.config{2,1}))
                testP.stopFlag = 1;
                stop(testP.config{2,1});
            end              
        end

        % Button pushed function: btnReset
        function btnResetButtonPushed(app, event)
            global testP;
            value = app.statebtnRun.Value;
            if value ~= true
                testP = testSet_Parameters();
                app.txtIndicator.Value = ' ';
                app.txtTestWearStatus.Value = ' ';
                app.cellArrayTextStatus = [];
                outPutStatus(app,'****Program Status****');
                set(app.statebtnRun,'value',0);
                set(app.cheBoxNeg,'value',0);
                set(app.ddwAlgo,'value',1);
            end            
        end

        % Button pushed function: btnTestCue
        function btnTestCueButtonPushed(app, event)
            global testP;
            testP.VCClickFlag = 1;  % Indicate a click is happened
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.edtFre = uieditfield(app.UIFigure, 'numeric');
            app.edtFre.Limits = [1 1000];
            app.edtFre.Value = 1;
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.edtTime = uieditfield(app.UIFigure, 'numeric');
            app.edtTime.Limits = [0 Inf];
            app.edtTime.Value = 1;
            app.btnStop = uibutton(app.UIFigure, 'push');
            app.btnStop.ButtonPushedFcn = createCallbackFcn(app, @btnStopButtonPushed, true);
            app.btnStop.Text = 'STOP';
            app.btnReset = uibutton(app.UIFigure, 'push');
            app.btnReset.ButtonPushedFcn = createCallbackFcn(app, @btnResetButtonPushed, true);
            app.btnReset.Text = 'RESET';
            app.IndicatorTextAreaLabel = uilabel(app.UIFigure);
            app.IndicatorTextAreaLabel.Text = 'Indicator';
            app.txtIndicator = uitextarea(app.UIFigure);
            app.txtIndicator.Editable = 'off';
            app.ProgStatusTextAreaLabel = uilabel(app.UIFigure);
            app.ProgStatusTextAreaLabel.Text = 'ProgStatus';
            app.txtTestStatus = uitextarea(app.UIFigure);
            app.txtTestStatus.Editable = 'off';
            app.txtTestStatus.Value = {'****Program Status****'};
            app.AlogrithmDropDownLabel = uilabel(app.UIFigure);
            app.AlogrithmDropDownLabel.Text = 'Alogrithm';
            app.ddwAlgo = uidropdown(app.UIFigure);
            app.ddwAlgo.Items = {'Algo 1', 'Algo 2', 'Algo 3', 'Algo 4'};
            app.ddwAlgo.ItemsData = [1 2 3 4];
            app.ddwAlgo.Value = 1;
            app.statebtnRun = uibutton(app.UIFigure, 'state');
            app.statebtnRun.ValueChangedFcn = createCallbackFcn(app, @statebtnRunValueChanged, true);
            app.statebtnRun.Text = 'RUN';
            app.cheBoxNeg = uicheckbox(app.UIFigure);
            app.cheBoxNeg.Text = '';
            app.NegativeSignLabel = uilabel(app.UIFigure);
            app.NegativeSignLabel.Text = 'NegativeSign';
            app.btnTestCue = uibutton(app.UIFigure, 'push');
            app.btnTestCue.ButtonPushedFcn = createCallbackFcn(app, @btnTestCueButtonPushed, true);
            app.btnTestCue.Text = 'Visual Cue';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.txtTestWearStatus = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_testing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end