classdef HCHPEXOv1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RBSEv1_0                     matlab.ui.Figure
        ddwUID                       matlab.ui.control.DropDown
        btnVisualCue                 matlab.ui.control.Button
        txtCommand                   matlab.ui.control.TextArea
        AssistiveTorqueCommandLabel  matlab.ui.control.Label
        RBSECo10Label                matlab.ui.control.Label
        ProgramStatusLabel           matlab.ui.control.Label
        ddwSubject                   matlab.ui.control.DropDown
        SubjectDropDownLabel         matlab.ui.control.Label
        ddwCOM                       matlab.ui.control.DropDown
        COMLabel                     matlab.ui.control.Label
        ddwRTG                       matlab.ui.control.DropDown
        RTGStrategyDropDownLabel     matlab.ui.control.Label
        UIDStrategyDropDownLabel     matlab.ui.control.Label
        AsyBendingLabel              matlab.ui.control.Label
        chBoxAsyCom                  matlab.ui.control.CheckBox
        txtMode                      matlab.ui.control.TextArea
        WearerModeLabel              matlab.ui.control.Label
        txtInfo                      matlab.ui.control.TextArea
        SensorInfoTextAreaLabel      matlab.ui.control.Label
        txtStatus                    matlab.ui.control.TextArea
        btnReset                     matlab.ui.control.Button
        btnStop                      matlab.ui.control.Button
        btnRun                       matlab.ui.control.StateButton
        sLabel                       matlab.ui.control.Label
        HzLabel                      matlab.ui.control.Label
        edtRunTime                   matlab.ui.control.NumericEditField
        ExpectedRunningTimeLabel     matlab.ui.control.Label
        edtFre                       matlab.ui.control.NumericEditField
        HighLevelFrequencyEditFieldLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = public)
        cellArrayTextStatus % Text to show running status of high-level program
    end
    
    methods (Access = public)
        
         function outPutStatus(app,str)
            string = sprintf('%s\n', str);  % Transfer input str to 'string'
            app.cellArrayTextStatus = horzcat(app.cellArrayTextStatus,string); % Serial connect the string array for display
            app.txtStatus.Value = app.cellArrayTextStatus;  % Set the display
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: btnRun
        function btnRunValueChanged(app, event)
            global ExoP;
            global TempApp;
            TempApp = app;
            value = app.btnRun.Value;
            asyCompenValue = app.chBoxAsyCom.Value;   % asymmetric comp flag
            UIDstrategyMode = app.ddwUID.Value;       % UID strategy mode
            RTGstrategyMode = app.ddwRTG.Value;       % RTG strategy mode
            SubjectNumber = app.ddwSubject.Value;     % Selected subject
            COMPort = app.ddwCOM.Value;               % COM Port
            MainFreq = get(app.edtFre,'value');       % High-level Frequency
            MaxRunTime = get(app.edtRunTime,'value'); % Expected Running Time            
            app.txtInfo.Value = ' ';
            app.txtMode.Value = ' ';
            app.txtCommand.Value = ' ';
            app.cellArrayTextStatus = [];
            outPutStatus(app,'****Program Status****');
            if value == true
                ExoP = Set_Parameters(MainFreq,MaxRunTime);
                try                    
                    ExoP.asyEnable = asyCompenValue;
                    ExoP.SubjectNum = SubjectNumber;
                    ExoP.Subject = SubjectInit(SubjectNumber);
                    ExoP.UIDStrategy = UIDstrategyMode;
                    ExoP.UID = UIDInit(UIDstrategyMode);
                    ExoP.RTGStrategy = RTGstrategyMode;
                    ExoP.RTG = RTGInit(RTGstrategyMode);
                    ExoP.McuPort = strcat("COM",num2str(COMPort));
                    % Update to the newest initial mode as assigned through
                    % the GUI 
                    ExoP.MotionMode = [ExoP.NoTrans, ExoP.Exit, ExoP.None+ExoP.asyEnable*ExoP.asyCompen, ExoP.Stoop];
                catch
                    set(app.btnRun,'value',0);
                    errordlg('Error in input');
                    return;
                end
                outPutStatus(app,'Program Start.');
                pause(5/1000);
                EXOmain();
            end
        end

        % Button pushed function: btnStop
        function btnStopButtonPushed(app, event)
            % Should click after see High-level Control Running (Into timer loop)
            global ExoP;
            value = app.btnRun.Value;
            if value == true
                set(app.btnRun,'value',0);
                ExoP.stopButton = 1;
            end
            % pause(5/1000);
            % Here to stop the timer if timer is already set up
            if(~isempty(ExoP.config{2,1}) && isvalid(ExoP.config{2,1}))
                ExoP.stopFlag = 1;
                stop(ExoP.config{2,1});
            end            
        end

        % Button pushed function: btnReset
        function btnResetButtonPushed(app, event)
            global ExoP;
            value = app.btnRun.Value;
            if value ~= true
                MainFreq = get(app.edtFre,'value');       % High-level Frequency
                MaxRunTime = get(app.edtRunTime,'value'); % Expected Running Time                 
                ExoP = Set_Parameters(MainFreq,MaxRunTime);
                app.txtInfo.Value = ' ';
                app.txtMode.Value = ' ';
                app.txtCommand.Value = ' ';
                app.cellArrayTextStatus = [];
                outPutStatus(app,'****Program Status****');
                set(app.btnRun,'value',0);
                % % Here is to reset friction compensation
                % set(app.chBoxAsyCom,'value',0);
                % % Here is to reset UID and RTG strategy selection
                % set(app.ddwUID,'value',1);
                % set(app.ddwRTG,'value',1);
                % % Here is to reset serial com port
                % set(app.ddwCOM,'value',3);
                % % Here is to reset the subject
                % set(app.ddwSubject,'value',1);
            end
        end

        % Button pushed function: btnVisualCue
        function btnVisualCueButtonPushed(app, event)
            global ExoP;
            value = app.btnRun.Value;
            if value == true
                ExoP.VCClickFlag = 1;  % Change the flag the indicate a clicke motion
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RBSEv1_0 = uifigure('Visible', 'off');
            app.RBSEv1_0.Name = 'RBSEv1.0';
            app.Image = uiimage(app.RBSEv1_0);
            app.Image.ImageSource = 'ZXHPDemo.png';
            app.HighLevelFrequencyEditFieldLabel = uilabel(app.RBSEv1_0);
            app.HighLevelFrequencyEditFieldLabel.Text = 'High-Level Frequency';
            app.edtFre = uieditfield(app.RBSEv1_0, 'numeric');
            app.edtFre.Limits = [1 1000];
            app.edtFre.Value = 1;
            app.ExpectedRunningTimeLabel = uilabel(app.RBSEv1_0);
            app.ExpectedRunningTimeLabel.Text = 'Expected Running Time';
            app.edtRunTime = uieditfield(app.RBSEv1_0, 'numeric');
            app.edtRunTime.Limits = [1 Inf];
            app.edtRunTime.Value = 1;
            app.HzLabel = uilabel(app.RBSEv1_0);
            app.HzLabel.Text = 'Hz';
            app.sLabel = uilabel(app.RBSEv1_0);
            app.sLabel.Text = 's';
            app.btnRun = uibutton(app.RBSEv1_0, 'state');
            app.btnRun.ValueChangedFcn = createCallbackFcn(app, @btnRunValueChanged, true);
            app.btnRun.Text = 'Run';
            app.btnStop = uibutton(app.RBSEv1_0, 'push');
            app.btnStop.ButtonPushedFcn = createCallbackFcn(app, @btnStopButtonPushed, true);
            app.btnStop.Text = 'Stop';
            app.btnReset = uibutton(app.RBSEv1_0, 'push');
            app.btnReset.ButtonPushedFcn = createCallbackFcn(app, @btnResetButtonPushed, true);
            app.btnReset.Text = 'RESET';
            app.txtStatus = uitextarea(app.RBSEv1_0);
            app.txtStatus.Editable = 'off';
            app.txtStatus.Value = {'****Program Status****'};
            app.SensorInfoTextAreaLabel = uilabel(app.RBSEv1_0);
            app.SensorInfoTextAreaLabel.Text = 'Sensor Info';
            app.txtInfo = uitextarea(app.RBSEv1_0);
            app.txtInfo.Editable = 'off';
            app.WearerModeLabel = uilabel(app.RBSEv1_0);
            app.WearerModeLabel.Text = 'Wearer Mode';
            app.txtMode = uitextarea(app.RBSEv1_0);
            app.txtMode.Editable = 'off';
            app.chBoxAsyCom = uicheckbox(app.RBSEv1_0);
            app.chBoxAsyCom.Text = '';
            app.AsyBendingLabel = uilabel(app.RBSEv1_0);
            app.AsyBendingLabel.Text = 'AsyBending';
            app.UIDStrategyDropDownLabel = uilabel(app.RBSEv1_0);
            app.UIDStrategyDropDownLabel.Text = 'UID Strategy';
            app.RTGStrategyDropDownLabel = uilabel(app.RBSEv1_0);
            app.RTGStrategyDropDownLabel.Text = 'RTG Strategy';
            app.ddwRTG = uidropdown(app.RBSEv1_0);
            app.ddwRTG.Items = {'Impedance Strategy', 'Compen Strategy', 'Vel-based Compen', 'Imp_passive Strategy', 'Test Mode'};
            app.ddwRTG.ItemsData = [1 2 3 4 0];
            app.ddwRTG.Value = 1;
            app.COMLabel = uilabel(app.RBSEv1_0);
            app.COMLabel.Text = 'COM';
            app.ddwCOM = uidropdown(app.RBSEv1_0);
            app.ddwCOM.Items = {'COM3', 'COM4', 'COM5'};
            app.ddwCOM.ItemsData = [3 4 5];
            app.ddwCOM.Value = 3;
            app.SubjectDropDownLabel = uilabel(app.RBSEv1_0);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.ddwSubject = uidropdown(app.RBSEv1_0);
            app.ddwSubject.Items = {'HongPeng', 'Hugo'};
            app.ddwSubject.ItemsData = [1 2];
            app.ddwSubject.Value = 1;
            app.ProgramStatusLabel = uilabel(app.RBSEv1_0);
            app.ProgramStatusLabel.Text = 'Program Status';
            app.RBSECo10Label = uilabel(app.RBSEv1_0);
            app.RBSECo10Label.Text = 'RBSECo 1.0';
            app.AssistiveTorqueCommandLabel = uilabel(app.RBSEv1_0);
            app.AssistiveTorqueCommandLabel.Text = 'Assistive Torque Command';
            app.txtCommand = uitextarea(app.RBSEv1_0);
            app.txtCommand.Editable = 'off';
            app.btnVisualCue = uibutton(app.RBSEv1_0, 'push');
            app.btnVisualCue.ButtonPushedFcn = createCallbackFcn(app, @btnVisualCueButtonPushed, true);
            app.btnVisualCue.Text = 'Visual Cue';
            app.ddwUID = uidropdown(app.RBSEv1_0);
            app.ddwUID.Items = {'Basic Strategy', 'Developed Strategy', 'Test Mode'};
            app.ddwUID.ItemsData = [1 2 0];
            app.ddwUID.Value = 1;
            % Show the figure after all components are created
            app.RBSEv1_0.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HCHPEXOv1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RBSEv1_0)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RBSEv1_0)
        end
    end
end